classdef AutomaticApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutomaticoUIFigure              matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfiguraesTab                  matlab.ui.container.Tab
        mapNameEdtFd                    matlab.ui.control.EditField
        InsertmappathLabel              matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        NumerodePopulaoEditFieldLabel   matlab.ui.control.Label
        NumerodePopulaoEditField        matlab.ui.control.NumericEditField
        TrafegomdioporUtilizadorErlEditFieldLabel  matlab.ui.control.Label
        TrafegomdioporUtilizadorErlEditField  matlab.ui.control.NumericEditField
        TaxadepenetraoEditFieldLabel    matlab.ui.control.Label
        TaxadepenetraoEditField         matlab.ui.control.NumericEditField
        TaxadeutilizaoEditFieldLabel    matlab.ui.control.Label
        TaxadeutilizaoEditField         matlab.ui.control.NumericEditField
        FreqnciaCentralHzEditFieldLabel  matlab.ui.control.Label
        FreqnciaCentralHzEditField      matlab.ui.control.NumericEditField
        ModeloDropDownLabel             matlab.ui.control.Label
        ModeloDropDown                  matlab.ui.control.DropDown
        AntenaDropDownLabel             matlab.ui.control.Label
        AntenaDropDown                  matlab.ui.control.DropDown
        SensibilidadedomveldBmEditFieldLabel  matlab.ui.control.Label
        SensibilidadedomveldBmEditField  matlab.ui.control.NumericEditField
        AlturadasantenasmEditFieldLabel  matlab.ui.control.Label
        AlturadasantenasmEditField      matlab.ui.control.NumericEditField
        AlturadorecetormEditFieldLabel  matlab.ui.control.Label
        AlturadorecetormEditField       matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        GosEditFieldLabel               matlab.ui.control.Label
        GoSEditField                    matlab.ui.control.NumericEditField
        TaxadecoberturaminimadesejadaEditFieldLabel  matlab.ui.control.Label
        TaxadecoberturaminimadesejadaEditField  matlab.ui.control.NumericEditField
        PotnciatransmissordBmDropDownLabel  matlab.ui.control.Label
        PotnciatransmissordBmDropDown   matlab.ui.control.DropDown
        ResultadosTab                   matlab.ui.container.Tab
        InformaesLabel                  matlab.ui.control.Label
        UIElevPrx                       matlab.ui.control.UIAxes
        UIBestServer                    matlab.ui.control.UIAxes
        PotenciadBmrecebidapelomvelLabel  matlab.ui.control.Label
        CoberturaTotalEditFieldLabel    matlab.ui.control.Label
        CoberturaTotalEditField         matlab.ui.control.EditField
        NumAntennasEditFieldLabel       matlab.ui.control.Label
        NumAntennasEditField            matlab.ui.control.EditField
        RaioEditFieldLabel_2            matlab.ui.control.Label
        RaioEditField_2                 matlab.ui.control.EditField
        NumAntennasEditFieldLabel_3     matlab.ui.control.Label
        DEditField_3                    matlab.ui.control.EditField
        NumAntennasEditFieldLabel_4     matlab.ui.control.Label
        TrafegoTotalEditField_4         matlab.ui.control.EditField
        NumAntennasEditFieldLabel_5     matlab.ui.control.Label
        AreaCelulaEditField_5           matlab.ui.control.EditField
        SelecionaantenaDropDownLabel    matlab.ui.control.Label
        SelecionaantenaDropDown         matlab.ui.control.DropDown
        MostrarCoberturadaantenaselecionadaButton  matlab.ui.control.Button
        NCanaisEditFieldLabel_6         matlab.ui.control.Label
        NCanaisCelulaEditField_5        matlab.ui.control.EditField
        NCanaisCelulaEditFieldLabel_7   matlab.ui.control.Label
        NCanaisEditField_6              matlab.ui.control.EditField
        MostrarPotenciarecebidapelomovelButton  matlab.ui.control.Button
        MostrarSIRButton                matlab.ui.control.Button
        MostrarBestServerButton         matlab.ui.control.Button
        AreaTotalEditFieldLabel_7       matlab.ui.control.Label
        AreaTotalEditField_6            matlab.ui.control.EditField
    end

    
    properties (Access = public)
       Property % Description
       lat;
       lng;
       elev;
       fg;
       numAtennas=1;
       numAntennasRemv=0;
       lastnumAtennas=0;
       selectedTab=0;
       elevation_map;
       lat_map;
       lng_map;
       antenas="";
       modelo="";
       Ptx=21; %dbm
       Grx=0; %dBi
       txAltura=30;
       rxAltura=1.5;
       fc=2.1e9;
       convTorad=pi/180;
       lambda=3e8/2.1e9;
       
       SIaux;
       SI;
       prcCvrAnt;

       SAMPLES=512;
       lat_visible;
       lng_visible;
       elevation_visible;
       visgrid;
       d;
       lfs;
       vrtangles;
       hrzAngle;
       Gtx;
       Prx;
       visgridALL;
       prcCvr;
       PrxT;
       Rref;
       
       areaTotalkm2;
       nCanais;
       trafTotal;D;R;aCelula;eficUt;
       
        visgridALl;
        visgridAL2;
        prcCvrAnt1;
        numAntenas;
        bestServer;
        numCanaisPorCelula;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelecionaantenaDropDown
        function dropAntena(app, event)
            app.selectedTab = app.SelecionaantenaDropDown.Value;  
        end

        % Callback function: ConfiguraesTab, RunButton
        function btnRun(app, event)

            progressbar = uiprogressdlg(app.AutomaticoUIFigure,'Title','A otimizar',...
                'Indeterminate','on');
           close all
                   
           load(string(app.mapNameEdtFd.Value));
           app.elevation_map=elevation_map;
           app.lat_map=lat_map;
           app.lng_map=lng_map;
           
           app.SelecionaantenaDropDown.Items=""; 
           app.lat_visible=zeros(app.SAMPLES,app.SAMPLES);
           app.lng_visible=zeros(app.SAMPLES,app.SAMPLES);
           app.elevation_visible=zeros(app.SAMPLES,app.SAMPLES);
           app.visgrid=zeros(app.SAMPLES,app.SAMPLES);
           app.d=zeros(app.SAMPLES,app.SAMPLES);
           app.lfs=zeros(app.SAMPLES,app.SAMPLES);
           app.vrtangles=zeros(app.SAMPLES,app.SAMPLES);
           app.hrzAngle=zeros(app.SAMPLES,app.SAMPLES);
           app.Gtx=zeros(app.SAMPLES,app.SAMPLES);
           app.Prx=zeros(app.SAMPLES,app.SAMPLES);
           app.PrxT=NaN(app.SAMPLES,app.SAMPLES);
           app.visgridALL = logical(zeros(app.SAMPLES,app.SAMPLES));
           app.R = georefpostings([min(app.lat_map(:)),max(app.lat_map(:))],[min(app.lng_map(:)),max(app.lng_map(:))],[app.SAMPLES,app.SAMPLES],'ColumnsStartFrom','north');
           app.SIaux=zeros(app.SAMPLES,app.SAMPLES);
           app.prcCvrAnt=0;
           app.prcCvr=0;
           
           
           app.Rref = georefpostings([min(app.lat_map(:)),max(app.lat_map(:))],[min(app.lng_map(:)),max(app.lng_map(:))],[app.SAMPLES,app.SAMPLES],'ColumnsStartFrom','north');
          
           
           app.areaTotalkm2 = getArea(app.lat_map(1,1).*(app.convTorad),app.lng_map(1 ,1).*(app.convTorad),app.elevation_map(1 ,1).*(app.convTorad),[app.lat_map(1,app.SAMPLES).*(app.convTorad),app.lat_map(app.SAMPLES,1).*(app.convTorad)],[app.lng_map(1,app.SAMPLES).*(app.convTorad),app.lng_map(app.SAMPLES,1).*(app.convTorad)],[app.elevation_map(1,app.SAMPLES).*(app.convTorad),app.elevation_map(app.SAMPLES,1).*(app.convTorad)])/1e6;
           [app.nCanais,app.trafTotal,app.D,app.R,app.aCelula] = getNumChannels(app.NumerodePopulaoEditField.Value,app.TaxadepenetraoEditField.Value,app.TaxadeutilizaoEditField.Value,app.TrafegomdioporUtilizadorErlEditField.Value,app.GoSEditField.Value,app.areaTotalkm2,app.NEditField.Value);
           [app.nCanais,app.eficUt,~,~] = getHexCellind(app.nCanais,app.SAMPLES,app.trafTotal);
            
 
           app.PrxT=NaN(app.SAMPLES,app.SAMPLES);
           app.SIaux=zeros(app.SAMPLES,app.SAMPLES);
           app.visgridALl = logical(zeros(app.SAMPLES,app.SAMPLES));
           app.visgridAL2 = logical(zeros(app.SAMPLES,app.SAMPLES));
           app.prcCvrAnt1=zeros(app.SAMPLES,app.SAMPLES);
           
            app.prcCvr=0;
            app.numAntenas=1;
            visgridBuff = logical(zeros(app.SAMPLES,app.SAMPLES));
            salto=50;
            for i=1:salto:app.SAMPLES
                for j=salto:salto:app.SAMPLES       
                    [app.visgrid(:,:,app.numAntenas),~] = viewshed(app.elevation_map,app.Rref,app.lat_map(i,j),app.lng_map(i,j),app.AlturadasantenasmEditField.Value,app.AlturadorecetormEditField.Value);
                    app.prcCvrAnt1(i,j) = size(find(app.visgrid(:,:,app.numAntenas)==1),1)/(app.SAMPLES^2);
                    prcBuff(app.numAntenas)=app.prcCvrAnt1(i,j);
                    app.numAntenas=app.numAntenas+1;   
                end
            end
           
            first=0;
            prcBuffOrder=sort(prcBuff,'descend');
            n=2;
            sbrbf=0;
            app.numAntenas=1;
            while (app.prcCvr<app.TaxadecoberturaminimadesejadaEditField.Value && n<size(prcBuff,2))
                sobr=1;
                if(first==0)
                    app.numAntenas=1;
                    app.prcCvr=prcBuffOrder(1);
                    [x(app.numAntenas),y(app.numAntenas)] = find(app.prcCvrAnt1==app.prcCvr);
                    app.visgridAL2=app.visgrid(:,:,find(prcBuff==app.prcCvr));
                    
                    visgridBuff(:,:,1)=app.visgridAL2;
                    [app.lat_visible(:,:,app.numAntenas),app.lng_visible(:,:,app.numAntenas),app.elevation_visible(:,:,app.numAntenas),app.visgrid(:,:,app.numAntenas),app.d(:,:,app.numAntenas),app.vrtangles(:,:,app.numAntenas),app.lfs(:,:,app.numAntenas),app.hrzAngle(:,:,app.numAntenas),app.Gtx(:,:,app.numAntenas)]=cm_ParrachoMota2(app.lat_map,app.lng_map,app.elevation_map,app.lat_map(x(app.numAntenas),y(app.numAntenas)),app.lng_map(x(app.numAntenas),y(app.numAntenas)),app.elevation_map(x(app.numAntenas),y(app.numAntenas)),app.AlturadorecetormEditField.Value,app.AlturadasantenasmEditField.Value,app.FreqnciaCentralHzEditField.Value,app.ModeloDropDown.Value,app.SAMPLES,app.Rref,visgridBuff(:,:,1),string(app.AntenaDropDown.Value));
                    
                    PrxBuff =str2num(app.PotnciatransmissordBmDropDown.Value)+app.Gtx(:,:,app.numAntenas)+app.Grx-app.lfs(:,:,app.numAntenas);
                    
                    app.visgridAL2(PrxBuff<app.SensibilidadedomveldBmEditField.Value)=0;
                    first=1;
                else
                    while(sobr>0.96)
                        sis=size(find(app.prcCvrAnt1==prcBuffOrder(n)),1);
                        if (sis>1)
                            [a b]=find(app.prcCvrAnt1==prcBuffOrder(n));
                            for k = 1:size(a,1)
                                sbrbf1=app.visgrid(:,:,find(prcBuff==app.prcCvrAnt1(a(k),b(k))));
                                sbrbf(k)=size(find(sbrbf1 & app.visgridAL2)==1,1) / size(find(sbrbf1)==1,1);
                            end
                            x(app.numAntenas) = a(find(sbrbf==min(sbrbf)));
                            y(app.numAntenas) = b(find(sbrbf==min(sbrbf)));
                            sbrbf=[];
                        else
                            [x(app.numAntenas),y(app.numAntenas)] = find(app.prcCvrAnt1==prcBuffOrder(n));
                        end
                        visgridBuff(:,:,app.numAntenas)=app.visgrid(:,:,find(prcBuff==prcBuffOrder(n)));
                        %Verificar a potencia
                        [app.lat_visible(:,:,app.numAntenas),app.lng_visible(:,:,app.numAntenas),app.elevation_visible(:,:,app.numAntenas),app.visgrid(:,:,app.numAntenas),app.d(:,:,app.numAntenas),app.vrtangles(:,:,app.numAntenas),app.lfs(:,:,app.numAntenas),app.hrzAngle(:,:,app.numAntenas),app.Gtx(:,:,app.numAntenas)]=cm_ParrachoMota2(app.lat_map,app.lng_map,app.elevation_map,app.lat_map(x(app.numAntenas),y(app.numAntenas)),app.lng_map(x(app.numAntenas),y(app.numAntenas)),app.elevation_map(x(app.numAntenas),y(app.numAntenas)),app.AlturadorecetormEditField.Value,app.AlturadasantenasmEditField.Value,app.FreqnciaCentralHzEditField.Value,app.ModeloDropDown.Value,app.SAMPLES,app.Rref,visgridBuff(:,:,app.numAntenas),string(app.AntenaDropDown.Value));
                    
                        PrxBuff= str2num(app.PotnciatransmissordBmDropDown.Value)+app.Gtx(:,:,app.numAntenas)+app.Grx-app.lfs(:,:,app.numAntenas);
                    
                        vB=visgridBuff(:,:,app.numAntenas);
                        vB(PrxBuff<app.SensibilidadedomveldBmEditField.Value)=0;
                        visgridBuff(:,:,app.numAntenas)=vB;
                        
                        sobr = size(find(visgridBuff(:,:,app.numAntenas) & app.visgridAL2)==1 ,1) / size(find(visgridBuff(:,:,app.numAntenas))==1,1);
                        n=n+1;
                        if(n>=size(prcBuff,2))
                            break;
                        end
                    end
                    if(n<size(prcBuff,2))
                        app.visgridAL2=app.visgridAL2 | logical(visgridBuff(:,:,app.numAntenas));
                        app.prcCvr = size(find(app.visgridAL2==1),1)/(app.SAMPLES^2);
                        [app.lat_visible(:,:,app.numAntenas),app.lng_visible(:,:,app.numAntenas),app.elevation_visible(:,:,app.numAntenas),app.visgrid(:,:,app.numAntenas),app.d(:,:,app.numAntenas),app.vrtangles(:,:,app.numAntenas),app.lfs(:,:,app.numAntenas),app.hrzAngle(:,:,app.numAntenas),app.Gtx(:,:,app.numAntenas)]=cm_ParrachoMota2(app.lat_map,app.lng_map,app.elevation_map,app.lat_map(x(app.numAntenas),y(app.numAntenas)),app.lng_map(x(app.numAntenas),y(app.numAntenas)),app.elevation_map(x(app.numAntenas),y(app.numAntenas)),app.AlturadorecetormEditField.Value,app.AlturadasantenasmEditField.Value,app.FreqnciaCentralHzEditField.Value,app.ModeloDropDown.Value,app.SAMPLES,app.Rref,visgridBuff(:,:,app.numAntenas),string(app.AntenaDropDown.Value));
                    end
                end
                if(n<size(prcBuff,2))
                    
                    PrxBuff = str2num(app.PotnciatransmissordBmDropDown.Value)+app.Gtx(:,:,app.numAntenas)+app.Grx-app.lfs(:,:,app.numAntenas); 
                    PrxBuff(PrxBuff<app.SensibilidadedomveldBmEditField.Value)=NaN;
                    
                    app.Prx(:,:,app.numAntenas)=PrxBuff;
                    app.visgridALl = app.visgridALl | app.visgrid(:,:,app.numAntenas);
                    
                    app.prcCvrAnt(app.numAntenas) = size(find(app.visgrid(:,:,app.numAntenas)==1),1)/(app.SAMPLES^2);
                    
                    paux=10.^(app.Prx(:,:,app.numAntenas)./10);
                    paux(find(isnan(paux))) = 0;
                    app.SIaux = app.SIaux + paux;
                    
                    app.numAntenas=1+app.numAntenas;
                end
            end
            app.numAntenas=app.numAntenas-1;
            
            [app.PrxT,app.bestServer]=max(app.Prx,[],3);
            app.bestServer(~app.visgridAL2)=NaN;
            
            paux=10.^(app.PrxT./10);
            for i = 1:app.numAntenas 
                indSI=find(app.bestServer==i);
                app.SIaux(indSI) = app.SIaux(indSI) - paux(indSI);
            end
            app.SelecionaantenaDropDown.Items=string(1:app.numAntenas); 
            app.SI = 10.*log10(paux./app.SIaux);
            
            figure
            surf(app.lng_map(1,:), app.lat_map(:,1), app.elevation_map,app.PrxT, 'LineStyle',':');
            title(['Potência (dBm) recebida no movel - Percentagem de cobertura=',num2str(app.prcCvr*100),' % - Num Antenas=',num2str(app.numAntenas)])
            xlabel('Longitude (º)')
            ylabel('Latitude (º)')
            zlabel('Elevation (m)')
            figure
            surf(app.lng_map(1,:), app.lat_map(:,1), app.elevation_map,app.bestServer, 'LineStyle' , ':');
            title(['BestServer - Num Antenas=',num2str(app.numAntenas)])
            xlabel('Longitude (º)')
            ylabel('Latitude (º)')
            zlabel('Elevation (m)')
            
            figure
            surf(app.lng_map(1,:), app.lat_map(:,1), app.elevation_map,app.SI, 'LineStyle' , ':');
            title(['Signal Interference ratio (dBm) - Num Antenas=',num2str(app.numAntenas)])
            xlabel('Longitude (º)')
            ylabel('Latitude (º)')
            zlabel('Elevation (m)')
            
            
            app.aCelula = app.areaTotalkm2/app.numAntenas;
            app.R = sqrt(2*app.aCelula/(3*sqrt(3)));
            app.D = sqrt(3*app.NEditField.Value)*app.R; % km
            app.numCanaisPorCelula=ceil(app.nCanais/app.numAntenas);
            surf(app.UIElevPrx,app.lng_map(1,:), app.lat_map(:,1),app.elevation_map,app.PrxT, 'LineStyle',':');
            surf(app.UIBestServer,app.lng_map(1,:), app.lat_map(:,1),app.elevation_map,app.SI, 'LineStyle',':');
            
            app.CoberturaTotalEditField.Value = num2str(app.prcCvr*100);
            app.NumAntennasEditField.Value = num2str(app.numAntenas);
            app.RaioEditField_2.Value = num2str(app.R);
            app.DEditField_3.Value = num2str(app.D);
            app.AreaCelulaEditField_5.Value = num2str(app.aCelula);
            app.TrafegoTotalEditField_4.Value = num2str(app.trafTotal);
            app.NCanaisCelulaEditField_5.Value = num2str(app.numCanaisPorCelula);
            app.NCanaisEditField_6.Value = num2str(app.nCanais);
            app.AreaTotalEditField_6.Value  = num2str(app.areaTotalkm2);
            AA_func(max(app.lat_map(:)),min(app.lat_map(:)),min(app.lng_map(:)),max(app.lng_map(:)),app.PrxT,'urbanData')
            AB_func(max(app.lat_map(:)),min(app.lat_map(:)),min(app.lng_map(:)),max(app.lng_map(:)),app.SI,'SI')

            close(progressbar);
            
        end

        % Button pushed function: 
        % MostrarCoberturadaantenaselecionadaButton
        function btnPlotAntena(app, event)
            figure
            surf(app.lng_map(1,:), app.lat_map(:,1), app.elevation_map,app.Prx(:,:,str2double(app.SelecionaantenaDropDown.Value)), 'LineStyle',':');
            title(['Potência (dBm) recebida no movel - Percentagem de cobertura=',num2str(app.prcCvrAnt(str2double(app.SelecionaantenaDropDown.Value))*100),' %'])
        end

        % Button pushed function: 
        % MostrarPotenciarecebidapelomovelButton
        function btnShowPrxT(app, event)
            figure
            surf(app.lng_map(1,:), app.lat_map(:,1), app.elevation_map,app.PrxT, 'LineStyle',':');
            title(['Potência (dBm) recebida no movel - Percentagem de cobertura=',num2str(app.prcCvr*100),' % - Num Antenas=',num2str(app.numAntenas)])
        end

        % Button pushed function: MostrarSIRButton
        function btnShowSIR(app, event)
            figure
            surf(app.lng_map(1,:), app.lat_map(:,1), app.elevation_map,app.SI, 'LineStyle' , ':');
            title(['Signal Interference ratio (dBm) - Num Antenas=',num2str(app.numAntenas)])
        end

        % Button pushed function: MostrarBestServerButton
        function btnShowBestServer(app, event)
            figure
            surf(app.lng_map(1,:), app.lat_map(:,1), app.elevation_map,app.bestServer, 'LineStyle' , ':');
            title(['BestServer - Num Antenas=',num2str(app.numAntenas)])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutomaticoUIFigure = uifigure('Visible', 'off');
            app.AutomaticoUIFigure.Name = 'Automatico';
            app.TabGroup = uitabgroup(app.AutomaticoUIFigure);
            app.ConfiguraesTab = uitab(app.TabGroup);
            app.ConfiguraesTab.SizeChangedFcn = createCallbackFcn(app, @btnRun, true);
            app.ConfiguraesTab.Title = 'Configurações';
            app.mapNameEdtFd = uieditfield(app.ConfiguraesTab, 'text');
            app.mapNameEdtFd.Value = 'backup_Lisboa_512.mat';
            app.InsertmappathLabel = uilabel(app.ConfiguraesTab);
            app.InsertmappathLabel.Text = 'Insert map path:';
            app.RunButton = uibutton(app.ConfiguraesTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @btnRun, true);
            app.RunButton.Text = 'Run';
            app.NumerodePopulaoEditFieldLabel = uilabel(app.ConfiguraesTab);
            app.NumerodePopulaoEditFieldLabel.Text = 'Numero de População';
            app.NumerodePopulaoEditField = uieditfield(app.ConfiguraesTab, 'numeric');
            app.TrafegomdioporUtilizadorErlEditFieldLabel = uilabel(app.ConfiguraesTab);
            app.TrafegomdioporUtilizadorErlEditFieldLabel.Text = 'Trafego médio por Utilizador (Erl)';
            app.TrafegomdioporUtilizadorErlEditField = uieditfield(app.ConfiguraesTab, 'numeric');
            app.TaxadepenetraoEditFieldLabel = uilabel(app.ConfiguraesTab);
            app.TaxadepenetraoEditFieldLabel.Text = 'Taxa de penetração';
            app.TaxadepenetraoEditField = uieditfield(app.ConfiguraesTab, 'numeric');
            app.TaxadeutilizaoEditFieldLabel = uilabel(app.ConfiguraesTab);
            app.TaxadeutilizaoEditFieldLabel.Text = 'Taxa de utilização';
            app.TaxadeutilizaoEditField = uieditfield(app.ConfiguraesTab, 'numeric');
            app.FreqnciaCentralHzEditFieldLabel = uilabel(app.ConfiguraesTab);
            app.FreqnciaCentralHzEditFieldLabel.Text = 'Freqência Central (Hz)';
            app.FreqnciaCentralHzEditField = uieditfield(app.ConfiguraesTab, 'numeric');
            app.ModeloDropDownLabel = uilabel(app.ConfiguraesTab);
            app.ModeloDropDownLabel.Text = 'Modelo ';
            app.ModeloDropDown = uidropdown(app.ConfiguraesTab);
            app.ModeloDropDown.Items = {'Hata', 'IEEE802', 'Free Space'};
            app.ModeloDropDown.Value = 'Hata';
            app.AntenaDropDownLabel = uilabel(app.ConfiguraesTab);
            app.AntenaDropDownLabel.Text = 'Antena';
            app.AntenaDropDown = uidropdown(app.ConfiguraesTab);
            app.AntenaDropDown.Items = {'Omni', 'Allgon792085004', 'Allgon792085006', 'Allgon792085010', 'DUO4606000850', 'DUO4786500850'};
            app.AntenaDropDown.Value = 'DUO4786500850';
            app.SensibilidadedomveldBmEditFieldLabel = uilabel(app.ConfiguraesTab);
            app.SensibilidadedomveldBmEditFieldLabel.Text = 'Sensibilidade do móvel (dBm)';
            app.SensibilidadedomveldBmEditField = uieditfield(app.ConfiguraesTab, 'numeric');
            app.AlturadasantenasmEditFieldLabel = uilabel(app.ConfiguraesTab);
            app.AlturadasantenasmEditFieldLabel.Text = 'Altura das antenas (m)';
            app.AlturadasantenasmEditField = uieditfield(app.ConfiguraesTab, 'numeric');
            app.AlturadorecetormEditFieldLabel = uilabel(app.ConfiguraesTab);
            app.AlturadorecetormEditFieldLabel.Text = 'Altura do recetor (m)';
            app.AlturadorecetormEditField = uieditfield(app.ConfiguraesTab, 'numeric');
            app.NEditFieldLabel = uilabel(app.ConfiguraesTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.ConfiguraesTab, 'numeric');
            app.GosEditFieldLabel = uilabel(app.ConfiguraesTab);
            app.GosEditFieldLabel.Text = 'GoS';
            app.GoSEditField = uieditfield(app.ConfiguraesTab, 'numeric');
            app.TaxadecoberturaminimadesejadaEditFieldLabel = uilabel(app.ConfiguraesTab);
            app.TaxadecoberturaminimadesejadaEditFieldLabel.Text = 'Taxa de cobertura minima desejada';
            app.TaxadecoberturaminimadesejadaEditField = uieditfield(app.ConfiguraesTab, 'numeric');
            app.PotnciatransmissordBmDropDownLabel = uilabel(app.ConfiguraesTab);
            app.PotnciatransmissordBmDropDownLabel.Text = 'Potência transmissor (dBm)';
            app.PotnciatransmissordBmDropDown = uidropdown(app.ConfiguraesTab);
            app.PotnciatransmissordBmDropDown.Items = {'20', '21', '24'};
            app.PotnciatransmissordBmDropDown.Value = '20';
            app.ResultadosTab = uitab(app.TabGroup);
            app.ResultadosTab.Title = 'Resultados';
            app.InformaesLabel = uilabel(app.ResultadosTab);
            app.InformaesLabel.Text = 'Informações:';
            app.UIElevPrx = uiaxes(app.ResultadosTab);
            title(app.UIElevPrx, '')
            xlabel(app.UIElevPrx, '')
            ylabel(app.UIElevPrx, '')
            app.UIElevPrx.XTick = [];
            app.UIElevPrx.YTick = [];
            app.UIBestServer = uiaxes(app.ResultadosTab);
            title(app.UIBestServer, 'SIR (dBm)')
            xlabel(app.UIBestServer, '')
            ylabel(app.UIBestServer, '')
            app.UIBestServer.XTick = [];
            app.UIBestServer.YTick = [];
            app.PotenciadBmrecebidapelomvelLabel = uilabel(app.ResultadosTab);
            app.PotenciadBmrecebidapelomvelLabel.Text = 'Potencia (dBm) recebida pelo móvel';
            app.CoberturaTotalEditFieldLabel = uilabel(app.ResultadosTab);
            app.CoberturaTotalEditFieldLabel.Text = 'Cobertura Total (%)';
            app.CoberturaTotalEditField = uieditfield(app.ResultadosTab, 'text');
            app.CoberturaTotalEditField.Editable = 'off';
            app.NumAntennasEditFieldLabel = uilabel(app.ResultadosTab);
            app.NumAntennasEditFieldLabel.Text = 'Numero de Antenas';
            app.NumAntennasEditField = uieditfield(app.ResultadosTab, 'text');
            app.NumAntennasEditField.Editable = 'off';
            app.RaioEditFieldLabel_2 = uilabel(app.ResultadosTab);
            app.RaioEditFieldLabel_2.Text = 'Raio de cobertura das Células (km)';
            app.RaioEditField_2 = uieditfield(app.ResultadosTab, 'text');
            app.RaioEditField_2.Editable = 'off';
            app.NumAntennasEditFieldLabel_3 = uilabel(app.ResultadosTab);
            app.NumAntennasEditFieldLabel_3.Text = 'Distancia de reutilização (km)';
            app.DEditField_3 = uieditfield(app.ResultadosTab, 'text');
            app.DEditField_3.Editable = 'off';
            app.NumAntennasEditFieldLabel_4 = uilabel(app.ResultadosTab);
            app.NumAntennasEditFieldLabel_4.Text = 'Trafego Total do Sistema (Erl)';
            app.TrafegoTotalEditField_4 = uieditfield(app.ResultadosTab, 'text');
            app.TrafegoTotalEditField_4.Editable = 'off';
            app.NumAntennasEditFieldLabel_5 = uilabel(app.ResultadosTab);
            app.NumAntennasEditFieldLabel_5.Text = 'Area da Celula (km2)';
            app.AreaCelulaEditField_5 = uieditfield(app.ResultadosTab, 'text');
            app.AreaCelulaEditField_5.Editable = 'off';
            app.SelecionaantenaDropDownLabel = uilabel(app.ResultadosTab);
            app.SelecionaantenaDropDownLabel.Text = 'Selecionar antena';
            app.SelecionaantenaDropDown = uidropdown(app.ResultadosTab);
            app.SelecionaantenaDropDown.Items = {};
            app.SelecionaantenaDropDown.ValueChangedFcn = createCallbackFcn(app, @dropAntena, true);
            app.SelecionaantenaDropDown.Value = {};
            app.MostrarCoberturadaantenaselecionadaButton = uibutton(app.ResultadosTab, 'push');
            app.MostrarCoberturadaantenaselecionadaButton.ButtonPushedFcn = createCallbackFcn(app, @btnPlotAntena, true);
            app.MostrarCoberturadaantenaselecionadaButton.Text = 'Mostrar Cobertura da antena selecionada';
            app.NCanaisEditFieldLabel_6 = uilabel(app.ResultadosTab);
            app.NCanaisEditFieldLabel_6.Text = 'Numero de canais por celula';
            app.NCanaisCelulaEditField_5 = uieditfield(app.ResultadosTab, 'text');
            app.NCanaisCelulaEditField_5.Editable = 'off';
            app.NCanaisCelulaEditFieldLabel_7 = uilabel(app.ResultadosTab);
            app.NCanaisCelulaEditFieldLabel_7.Text = 'Numero de canais do Sistema';
            app.NCanaisEditField_6 = uieditfield(app.ResultadosTab, 'text');
            app.NCanaisEditField_6.Editable = 'off';
            app.MostrarPotenciarecebidapelomovelButton = uibutton(app.ResultadosTab, 'push');
            app.MostrarPotenciarecebidapelomovelButton.ButtonPushedFcn = createCallbackFcn(app, @btnShowPrxT, true);
            app.MostrarPotenciarecebidapelomovelButton.Text = 'Mostrar Potencia recebida pelo movel';
            app.MostrarSIRButton = uibutton(app.ResultadosTab, 'push');
            app.MostrarSIRButton.ButtonPushedFcn = createCallbackFcn(app, @btnShowSIR, true);
            app.MostrarSIRButton.Text = 'Mostrar SIR';
            app.MostrarBestServerButton = uibutton(app.ResultadosTab, 'push');
            app.MostrarBestServerButton.ButtonPushedFcn = createCallbackFcn(app, @btnShowBestServer, true);
            app.MostrarBestServerButton.Text = 'Mostrar BestServer';
            app.AreaTotalEditFieldLabel_7 = uilabel(app.ResultadosTab);
            app.AreaTotalEditFieldLabel_7.Text = 'Area do mapa (km2)';
            app.AreaTotalEditField_6 = uieditfield(app.ResultadosTab, 'text');
            app.AreaTotalEditField_6.Editable = 'off';
            % Show the figure after all components are created
            app.AutomaticoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutomaticApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutomaticoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutomaticoUIFigure)
        end
    end
end