classdef UMTS_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComunicacoesMoveis2019SimulatorUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        WelcomeTab                     matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        UMTSCenrioUrbanoLabel          matlab.ui.control.Label
        ComunicaesMoveisLabel          matlab.ui.control.Label
        ModoSimulaoButton              matlab.ui.control.Button
        ModoAutomticoButton            matlab.ui.control.Button
        SimulatorTab                   matlab.ui.container.Tab
        PlaceAntennaButton             matlab.ui.control.Button
        SimulateButton                 matlab.ui.control.Button
        NumAntennasEditFieldLabel      matlab.ui.control.Label
        NumAntennasEditField           matlab.ui.control.EditField
        mapNameEdtFd                   matlab.ui.control.EditField
        LoadMapButton                  matlab.ui.control.Button
        RemoveAntennaButton            matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        InsertmappathLabel             matlab.ui.control.Label
        PrxTab                         matlab.ui.container.Tab
        CoberturaTotalEditFieldLabel   matlab.ui.control.Label
        CoberturaTotalEditField        matlab.ui.control.EditField
        InformaoCoberturaantenasLabel  matlab.ui.control.Label
        TextInfo                       matlab.ui.control.TextArea
        UIElevPrx                      matlab.ui.control.UIAxes
        BestServerTab                  matlab.ui.container.Tab
        UIBestServer                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       lat;
       lng;
       elev;
       fg;
       numAtennas=0;
       numAntennasRemv=0;
       lastnumAtennas=0;
       selectedTab=0;
       elevation_map;
       lat_map;
       lng_map;
       antennas=["omni" ,"06", "duo886", "14","duo4868"];
       
       Ptx=21; %dbm
       Grx=1;
       txAltura=25;
       rxAltura=1;
       fc=2.1e9;
%        Re=earthRadius('meters');
%        radius=Re;
       convTorad=pi/180;
       lambda=3e8/2.1e9;
       
       SAMPLES=512;
       lat_visible;
       lng_visible;
       elevation_visible;
       visgrid;
       d;
       lfs;
       vrtangles;
       hrzAngle;
       Gtx;
       Prx;
       visgridALL;
       prcCvr;
       PrxT;
       R;
    end
    
    methods (Access = private) 
        
    end
    
    methods (Access = public)
        function readData(app)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: PlaceAntennaButton
        function btnChsCoor(app, event)
            coor=getCursorInfo(app.fg);
            if ~isempty(coor)
                app.numAtennas=app.numAtennas+1;
                app.lng(app.numAtennas)=coor(1).Position(1);  
                app.lat(app.numAtennas)=coor(1).Position(2);  
                app.elev(app.numAtennas)=coor(1).Position(3);
                hold(app.UIAxes, 'on')
                plot3(app.UIAxes,app.lng(app.numAtennas), app.lat(app.numAtennas),app.elev(app.numAtennas),'kx','MarkerSize',30);
                hold(app.UIAxes, 'off')
                app.NumAntennasEditField.Value = string(app.numAtennas);
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.selectedTab = app.TabGroup.SelectedTab;
            if (app.selectedTab.Title == "Simulator") app.NumAntennasEditField.Value = string(app.numAtennas); end
           % readData(app);
        end

        % Button pushed function: SimulateButton
        function btnSim(app, event)
               if app.lastnumAtennas~=app.numAtennas
                   if ~app.lastnumAtennas
                       app.lat_visible=zeros(app.SAMPLES,app.SAMPLES);
                       app.lng_visible=zeros(app.SAMPLES,app.SAMPLES);
                       app.elevation_visible=zeros(app.SAMPLES,app.SAMPLES);
                       app.visgrid=zeros(app.SAMPLES,app.SAMPLES);
                       app.d=zeros(app.SAMPLES,app.SAMPLES);
                       app.lfs=zeros(app.SAMPLES,app.SAMPLES);
                       app.vrtangles=zeros(app.SAMPLES,app.SAMPLES);
                       app.hrzAngle=zeros(app.SAMPLES,app.SAMPLES);
                       app.Gtx=zeros(app.SAMPLES,app.SAMPLES);
                       app.Prx=zeros(app.SAMPLES,app.SAMPLES);
                       app.PrxT=NaN(app.SAMPLES,app.SAMPLES);
                       app.visgridALL = logical(zeros(app.SAMPLES,app.SAMPLES));
                       app.R = georefpostings([min(app.lat_map(:)),max(app.lat_map(:))],[min(app.lng_map(:)),max(app.lng_map(:))],[app.SAMPLES,app.SAMPLES],'ColumnsStartFrom','north');
                   end
                        for i = app.lastnumAtennas+1:app.numAtennas
                            [app.lat_visible(:,:,i),app.lng_visible(:,:,i),app.elevation_visible(:,:,i),app.visgrid(:,:,i),app.d(:,:,i),app.vrtangles(:,:,i),app.lfs(:,:,i),app.hrzAngle(:,:,i)]=cm_ParrachoMota(app.lat_map,app.lng_map,app.elevation_map,app.lat(i),app.lng(i),app.elev(i),app.rxAltura,app.txAltura,app.fc,'Hata',app.SAMPLES,app.R);
                            app.Gtx(:,:,i)=getGtxAntennasPM(app.hrzAngle(:,:,i),app.vrtangles(:,:,i),"omni");
                            app.Prx(:,:,i)=app.Ptx+app.Gtx(:,:,i)+app.Grx-app.lfs(:,:,i);
                            app.visgridALL = app.visgridALL | app.visgrid(:,:,i);
                            PrAux=app.Prx(:,:,i);
                            app.PrxT(logical(app.visgrid(:,:,i)))= PrAux(logical(app.visgrid(:,:,i)));
                        end
                        
                        app.visgrid=logical(app.visgrid);
                        app.prcCvr = size(find(app.visgridALL==1),1)/(app.SAMPLES^2);
                        app.CoberturaTotalEditField.Value = string(app.prcCvr);
                        
                        hold(app.UIElevPrx,'on');
                        surf(app.UIElevPrx,app.lng_map(1,:), app.lat_map(:,1), app.elevation_map,app.PrxT, 'LineStyle' , ':');
                        hold(app.UIElevPrx,'off');
                        
                        [~,bestServerPixel]=max(app.Prx,[],3);
                        bestServerPixel(~app.visgridALL)=NaN;
                        surf(app.UIBestServer,app.lng_map(1,:), app.lat_map(:,1), app.elevation_map,bestServerPixel, 'LineStyle' , ':')
                        app.lastnumAtennas=app.numAtennas;
                        AA_func(max(app.lat_map(:)),min(app.lat_map(:)),min(app.lng_map(:)),max(app.lng_map(:)),app.PrxT,'urbanData')
                        %imshow('urbanData.png',app.Image4);
                end
        end

        % Button pushed function: LoadMapButton
        function btnLoadMap(app, event)
            if exist(string(app.mapNameEdtFd.Value))
                load(string(app.mapNameEdtFd.Value));
                if ~app.numAtennas
                    app.lat=0;
                    app.lng=0;
                    app.elev=0;
                    app.numAtennas=0;
                    app.lastnumAtennas=0;
                    app.NumAntennasEditField.Value = string(app.numAtennas);
                end
                meshc(app.UIAxes,lng_map(1,:), lat_map(:,1),elevation_map);
                app.fg = datacursormode(ancestor(app.UIAxes, 'figure'));
                app.SimulateButton.Visible="on";
                app.PlaceAntennaButton.Visible="on";
                app.NumAntennasEditField.Visible="on";
                app.elevation_map=elevation_map;
                app.lat_map=lat_map;
                app.lng_map=lng_map;
            end
        end

        % Button pushed function: RemoveAntennaButton
        function btnRemove(app, event)
            coor=getCursorInfo(app.fg);
            if ~isempty(coor) && app.numAtennas>0
%                 lng=coor(1).Position(1);  
%                 lat=coor(1).Position(2);  
%                 elev=coor(1).Position(3);
                elevF=find(coor(1).Position(3)==app.elev);latF=find(coor(1).Position(2)==app.lat);lngF=find(coor(1).Position(1)==app.lng);
                
                app.numAntennasRemv=app.numAntennasRemv+1;
                app.numAtennas=app.numAtennas-1;
                hold(app.UIAxes, 'on')
                plot3(app.UIAxes,app.lng(app.numAtennas), app.lat(app.numAtennas),app.elev(app.numAtennas),'.k','MarkerSize',0.01);
                hold(app.UIAxes, 'off')
                app.NumAntennasEditField.Value = string(app.numAtennas);
            end
        end

        % Button pushed function: ModoSimulaoButton
        function btnModoSim(app, event)
            simulationApp
        end

        % Button pushed function: ModoAutomticoButton
        function btnModoAuto(app, event)
            AutomaticApp
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComunicacoesMoveis2019SimulatorUIFigure = uifigure('Visible', 'off');
            app.ComunicacoesMoveis2019SimulatorUIFigure.Name = 'Comunicacoes Moveis 2019 Simulator';
            app.TabGroup = uitabgroup(app.ComunicacoesMoveis2019SimulatorUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.Image = uiimage(app.WelcomeTab);
            app.Image.ImageSource = 'estg.jpg';
            app.UMTSCenrioUrbanoLabel = uilabel(app.WelcomeTab);
            app.UMTSCenrioUrbanoLabel.Text = 'UMTS - Cenário Urbano';
            app.ComunicaesMoveisLabel = uilabel(app.WelcomeTab);
            app.ComunicaesMoveisLabel.Text = 'Comunicações Moveis';
            app.ModoSimulaoButton = uibutton(app.WelcomeTab, 'push');
            app.ModoSimulaoButton.ButtonPushedFcn = createCallbackFcn(app, @btnModoSim, true);
            app.ModoSimulaoButton.Text = 'Modo Simulação';
            app.ModoAutomticoButton = uibutton(app.WelcomeTab, 'push');
            app.ModoAutomticoButton.ButtonPushedFcn = createCallbackFcn(app, @btnModoAuto, true);
            app.ModoAutomticoButton.Text = 'Modo Automático';
            app.SimulatorTab = uitab(app.TabGroup);
            app.SimulatorTab.Title = 'Simulator';
            app.PlaceAntennaButton = uibutton(app.SimulatorTab, 'push');
            app.PlaceAntennaButton.ButtonPushedFcn = createCallbackFcn(app, @btnChsCoor, true);
            app.PlaceAntennaButton.Text = 'Place Antenna';
            app.SimulateButton = uibutton(app.SimulatorTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @btnSim, true);
            app.SimulateButton.Text = 'Simulate';
            app.NumAntennasEditFieldLabel = uilabel(app.SimulatorTab);
            app.NumAntennasEditFieldLabel.Text = 'Num Antennas';
            app.NumAntennasEditField = uieditfield(app.SimulatorTab, 'text');
            app.NumAntennasEditField.Editable = 'off';
            app.mapNameEdtFd = uieditfield(app.SimulatorTab, 'text');
            app.mapNameEdtFd.Value = 'backup_Lisboa_512.mat';
            app.LoadMapButton = uibutton(app.SimulatorTab, 'push');
            app.LoadMapButton.ButtonPushedFcn = createCallbackFcn(app, @btnLoadMap, true);
            app.LoadMapButton.Text = 'Load Map';
            app.RemoveAntennaButton = uibutton(app.SimulatorTab, 'push');
            app.RemoveAntennaButton.ButtonPushedFcn = createCallbackFcn(app, @btnRemove, true);
            app.RemoveAntennaButton.Text = 'Remove Antenna';
            app.UIAxes = uiaxes(app.SimulatorTab);
            title(app.UIAxes, 'Elevation Map')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.InsertmappathLabel = uilabel(app.SimulatorTab);
            app.InsertmappathLabel.Text = 'Insert map path:';
            app.PrxTab = uitab(app.TabGroup);
            app.PrxTab.Title = 'Prx';
            app.CoberturaTotalEditFieldLabel = uilabel(app.PrxTab);
            app.CoberturaTotalEditFieldLabel.Text = 'Cobertura Total';
            app.CoberturaTotalEditField = uieditfield(app.PrxTab, 'text');
            app.CoberturaTotalEditField.Editable = 'off';
            app.InformaoCoberturaantenasLabel = uilabel(app.PrxTab);
            app.InformaoCoberturaantenasLabel.Text = 'Informação Cobertura antenas';
            app.TextInfo = uitextarea(app.PrxTab);
            app.TextInfo.Editable = 'off';
            app.UIElevPrx = uiaxes(app.PrxTab);
            title(app.UIElevPrx, '')
            xlabel(app.UIElevPrx, '')
            ylabel(app.UIElevPrx, '')
            app.UIElevPrx.XTick = [];
            app.UIElevPrx.YTick = [];
            app.BestServerTab = uitab(app.TabGroup);
            app.BestServerTab.Title = 'Best Server';
            app.UIBestServer = uiaxes(app.BestServerTab);
            title(app.UIBestServer, 'Best Server')
            xlabel(app.UIBestServer, '')
            ylabel(app.UIBestServer, '')
            app.UIBestServer.XTick = [];
            app.UIBestServer.YTick = [];
            % Show the figure after all components are created
            app.ComunicacoesMoveis2019SimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UMTS_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComunicacoesMoveis2019SimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComunicacoesMoveis2019SimulatorUIFigure)
        end
    end
end