classdef simulationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimuladorUIFigure          matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        SimulacaoTab               matlab.ui.container.Tab
        ColocarAntenaButton        matlab.ui.control.Button
        SimularButton              matlab.ui.control.Button
        NumAntennasEditFieldLabel  matlab.ui.control.Label
        NumAntennasEditField       matlab.ui.control.EditField
        mapNameEdtFd               matlab.ui.control.EditField
        CarregarMapaButton         matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        InsertmappathLabel         matlab.ui.control.Label
        ModeloDropDownLabel        matlab.ui.control.Label
        ModeloDropDown             matlab.ui.control.DropDown
        AntenaDropDownLabel        matlab.ui.control.Label
        AntenaDropDown             matlab.ui.control.DropDown
        AlturadoTxmDropDownLabel   matlab.ui.control.Label
        AlturadoTxmDropDown        matlab.ui.control.DropDown
        AlturadoRxmDropDownLabel   matlab.ui.control.Label
        AlturadoRxmDropDown        matlab.ui.control.DropDown
    end

    
    properties (Access = public)
       lat;
       lng;
       elev;
       fg;
       numAtennas=0;
       numAntennasRemv=0;
       lastnumAtennas=0;
       selectedTab=0;
       elevation_map;
       lat_map;
       lng_map;
       antenas="";
       modelo="";
       Ptx=21; %dbm
       Grx=0; %dBi
       txAltura=30;
       rxAltura=1.5;
       fc=2.1e9;
       convTorad=pi/180;
       lambda=3e8/2.1e9;
       
       SIaux;
       SI;
       prcCvrAnt;
       
       SAMPLES=512;
       lat_visible;
       lng_visible;
       elevation_visible;
       visgrid;
       d;
       lfs;
       vrtangles;
       hrzAngle;
       Gtx;
       Prx;
       visgridALL;
       prcCvr;
       PrxT;
       R;
    end
    
    methods (Access = private) 
        
    end
    
    methods (Access = public)
        function readData(app)
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CarregarMapaButton
        function btnLoad(app, event)
            if exist(string(app.mapNameEdtFd.Value))
                load(string(app.mapNameEdtFd.Value));
                if ~app.numAtennas
                    app.lat=0;
                    app.lng=0;
                    app.elev=0;
                    app.numAtennas=0;
                    app.lastnumAtennas=0;
                    app.NumAntennasEditField.Value = string(app.numAtennas);
                end
                meshc(app.UIAxes,lng_map(1,:), lat_map(:,1),elevation_map);
                app.fg = datacursormode(ancestor(app.UIAxes, 'figure'));
                app.SimularButton.Visible="on";
                app.ColocarAntenaButton.Visible="on";
                app.NumAntennasEditField.Visible="on";
                app.elevation_map=elevation_map;
                app.lat_map=lat_map;
                app.lng_map=lng_map;
            end
        end

        % Button pushed function: ColocarAntenaButton
        function btnChsCoor(app, event)
            coor=getCursorInfo(app.fg);
            if ~isempty(coor)
                app.numAtennas=app.numAtennas+1;
                app.lng(app.numAtennas)=coor(1).Position(1);  
                app.lat(app.numAtennas)=coor(1).Position(2);  
                app.elev(app.numAtennas)=coor(1).Position(3);
                app.antenas(:,app.numAtennas) = string(app.AntenaDropDown.Value);
                app.modelo(:,app.numAtennas) = string(app.ModeloDropDown.Value);
                app.rxAltura(:,app.numAtennas) = str2num(app.AlturadoRxmDropDown.Value);
                app.txAltura(:,app.numAtennas) = str2num(app.AlturadoTxmDropDown.Value);
            
                hold(app.UIAxes, 'on')
                plot3(app.UIAxes,app.lng(app.numAtennas), app.lat(app.numAtennas),app.elev(app.numAtennas),'kx','MarkerSize',30);
                hold(app.UIAxes, 'off')
                app.NumAntennasEditField.Value = string(app.numAtennas);
            end
        end

        % Callback function
        function btnRemove(app, event)
            coor=getCursorInfo(app.fg);
            if ~isempty(coor) && app.numAtennas>0
%                 lng=coor(1).Position(1);  
%                 lat=coor(1).Position(2);  
%                 elev=coor(1).Position(3);
                elevF=find(coor(1).Position(3)==app.elev);latF=find(coor(1).Position(2)==app.lat);lngF=find(coor(1).Position(1)==app.lng);
                
                app.numAntennasRemv=app.numAntennasRemv+1;
                app.numAtennas=app.numAtennas-1;
                hold(app.UIAxes, 'on')
                plot3(app.UIAxes,app.lng(app.numAtennas), app.lat(app.numAtennas),app.elev(app.numAtennas),'.k','MarkerSize',0.01);
                hold(app.UIAxes, 'off')
                app.NumAntennasEditField.Value = string(app.numAtennas);
            end
        end

        % Button pushed function: SimularButton
        function btnSimulate(app, event)
            if app.lastnumAtennas~=app.numAtennas
                close all
                % figu = uifigure;
                 progressbar = uiprogressdlg(app.SimuladorUIFigure,'Title','A colocar as antenas',...
                'Indeterminate','on');
                   if ~app.lastnumAtennas
                       app.lat_visible=zeros(app.SAMPLES,app.SAMPLES);
                       app.lng_visible=zeros(app.SAMPLES,app.SAMPLES);
                       app.elevation_visible=zeros(app.SAMPLES,app.SAMPLES);
                       app.visgrid=zeros(app.SAMPLES,app.SAMPLES);
                       app.d=zeros(app.SAMPLES,app.SAMPLES);
                       app.lfs=zeros(app.SAMPLES,app.SAMPLES);
                       app.vrtangles=zeros(app.SAMPLES,app.SAMPLES);
                       app.hrzAngle=zeros(app.SAMPLES,app.SAMPLES);
                       app.Gtx=zeros(app.SAMPLES,app.SAMPLES);
                       app.Prx=zeros(app.SAMPLES,app.SAMPLES);
                       app.PrxT=NaN(app.SAMPLES,app.SAMPLES);
                       app.visgridALL = logical(zeros(app.SAMPLES,app.SAMPLES));
                       app.R = georefpostings([min(app.lat_map(:)),max(app.lat_map(:))],[min(app.lng_map(:)),max(app.lng_map(:))],[app.SAMPLES,app.SAMPLES],'ColumnsStartFrom','north');
                       app.SIaux=zeros(app.SAMPLES,app.SAMPLES);
                       app.prcCvrAnt=0;
                   end
                        for i = app.lastnumAtennas+1:app.numAtennas 
                            [app.lat_visible(:,:,i),app.lng_visible(:,:,i),app.elevation_visible(:,:,i),app.visgrid(:,:,i),app.d(:,:,i),app.vrtangles(:,:,i),app.lfs(:,:,i),app.hrzAngle(:,:,i),app.Gtx(:,:,i)]=cm_ParrachoMota(app.lat_map,app.lng_map,app.elevation_map,app.lat(i),app.lng(i),app.elev(i),app.rxAltura(:,i),app.txAltura(:,i),app.fc,app.modelo(i),app.SAMPLES,app.R,app.antenas(:,i)); 
 
                            app.Prx(:,:,i)=app.Ptx+app.Gtx(:,:,i)+app.Grx-app.lfs(:,:,i);
                            app.visgridALL = app.visgridALL | app.visgrid(:,:,i);
                            
                            app.prcCvrAnt(i) = size(find(app.visgrid(:,:,i)==1),1)/(app.SAMPLES^2);     
                        end
                        
                        app.visgrid=logical(app.visgrid);
                        app.prcCvr = size(find(app.visgridALL==1),1)/(app.SAMPLES^2);
                        %app.CoberturaTotalEditField.Value = string(app.prcCvr);
                        
                        [app.PrxT,bestServer]=max(app.Prx,[],3);
                        bestServer(~app.visgridALL)=NaN;
                        
                        for i=1:app.numAtennas
                            paux=10.^(app.Prx(:,:,i)./10);
                            paux(find(isnan(paux))) = 0;
                            app.SIaux = app.SIaux + paux;
                        end
                            
                        paux=10.^(app.PrxT./10);
                        for i = 1:app.numAtennas
                            indSI=find(bestServer==i);
                            app.SIaux(indSI) = app.SIaux(indSI) - paux(indSI);
                        end
                        app.SI = 10.*log10(paux./app.SIaux);
                        
                       %hold(app.UIElevPrx,'on');
                       %surf(app.UIElevPrx,app.lng_map(1,:), app.lat_map(:,1), app.elevation_map,app.PrxT, 'LineStyle' , ':');
                       % hold(app.UIElevPrx,'off');
                       figure
                       surf(app.lng_map(1,:), app.lat_map(:,1), app.elevation_map,app.PrxT, 'LineStyle' , ':');
                       title(['Potência (dBm) recebida no movel - Percentagem de cobertura=',num2str(app.prcCvr*100),' % - Num Antenas=',num2str(app.numAtennas)])
                       xlabel('Longitude (º)') 
                       ylabel('Latitude (º)')
                       zlabel('Elevation (m)')
                       
                       figure
                       surf(app.lng_map(1,:), app.lat_map(:,1), app.elevation_map,bestServer, 'LineStyle' , ':')
                       title(['Best Server - Numero de antenas=' ,num2str(app.numAtennas)]);
                       xlabel('Longitude (º)') 
                       ylabel('Latitude (º)')
                       zlabel('Elevation (m)')
                       
                       if(app.numAtennas>1)
      
                           figure
                           surf(app.lng_map(1,:), app.lat_map(:,1), app.elevation_map,app.SI, 'LineStyle' , ':')
                           title("Sinal Interference ratio (dBm) - Numero de antenas="+num2str(app.numAtennas)); 
                           xlabel('Longitude (º)') 
                           ylabel('Latitude (º)')
                           zlabel('Elevation (m)') 
                       end
                                    
                       app.lastnumAtennas=app.numAtennas;
                       AA_func(max(app.lat_map(:)),min(app.lat_map(:)),min(app.lng_map(:)),max(app.lng_map(:)),app.PrxT,'urbanPower')
                       AB_func(max(app.lat_map(:)),min(app.lat_map(:)),min(app.lng_map(:)),max(app.lng_map(:)),app.SI,'SI')
                        
                       close(progressbar);
                       %delete(figu);
                       %imshow('urbanData.png',app.Image4);
            end   
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.selectedTab = app.TabGroup.SelectedTab;
            if (app.selectedTab.Title == "Simulacao") 
                app.NumAntennasEditField.Value = string(app.numAtennas); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimuladorUIFigure = uifigure('Visible', 'off');
            app.SimuladorUIFigure.Name = 'Simulador';
            app.TabGroup = uitabgroup(app.SimuladorUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SimulacaoTab = uitab(app.TabGroup);
            app.SimulacaoTab.Title = 'Simulacao';
            app.ColocarAntenaButton = uibutton(app.SimulacaoTab, 'push');
            app.ColocarAntenaButton.ButtonPushedFcn = createCallbackFcn(app, @btnChsCoor, true);
            app.ColocarAntenaButton.Text = 'Colocar Antena';
            app.SimularButton = uibutton(app.SimulacaoTab, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @btnSimulate, true);
            app.SimularButton.Text = 'Simular';
            app.NumAntennasEditFieldLabel = uilabel(app.SimulacaoTab);
            app.NumAntennasEditFieldLabel.Text = 'Num Antenas:';
            app.NumAntennasEditField = uieditfield(app.SimulacaoTab, 'text');
            app.NumAntennasEditField.Editable = 'off';
            app.mapNameEdtFd = uieditfield(app.SimulacaoTab, 'text');
            app.mapNameEdtFd.Value = 'backup_Lisboa_512.mat';
            app.CarregarMapaButton = uibutton(app.SimulacaoTab, 'push');
            app.CarregarMapaButton.ButtonPushedFcn = createCallbackFcn(app, @btnLoad, true);
            app.CarregarMapaButton.Text = 'Carregar Mapa';
            app.UIAxes = uiaxes(app.SimulacaoTab);
            title(app.UIAxes, 'Mapa de elevações')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.InsertmappathLabel = uilabel(app.SimulacaoTab);
            app.InsertmappathLabel.Text = 'Insert map path:';
            app.ModeloDropDownLabel = uilabel(app.SimulacaoTab);
            app.ModeloDropDownLabel.Text = 'Modelo ';
            app.ModeloDropDown = uidropdown(app.SimulacaoTab);
            app.ModeloDropDown.Items = {'Hata', 'IEEE802', 'Free Space'};
            app.ModeloDropDown.Value = 'Hata';
            app.AntenaDropDownLabel = uilabel(app.SimulacaoTab);
            app.AntenaDropDownLabel.Text = 'Antena';
            app.AntenaDropDown = uidropdown(app.SimulacaoTab);
            app.AntenaDropDown.Items = {'Omni', 'Allgon792085004', 'Allgon792085006', 'Allgon792085010', 'DUO4606000850', 'DUO4786500850'};
            app.AntenaDropDown.Value = 'Omni';
            app.AlturadoTxmDropDownLabel = uilabel(app.SimulacaoTab);
            app.AlturadoTxmDropDownLabel.Text = 'Altura do Tx (m)';
            app.AlturadoTxmDropDown = uidropdown(app.SimulacaoTab);
            app.AlturadoTxmDropDown.Items = {'25', '30', '35', '40', '50'};
            app.AlturadoTxmDropDown.Value = '25';
            app.AlturadoRxmDropDownLabel = uilabel(app.SimulacaoTab);
            app.AlturadoRxmDropDownLabel.Text = 'Altura do Rx (m)';
            app.AlturadoRxmDropDown = uidropdown(app.SimulacaoTab);
            app.AlturadoRxmDropDown.Items = {'0', '1', '1.5', '2', '3'};
            app.AlturadoRxmDropDown.Value = '0';
            % Show the figure after all components are created
            app.SimuladorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimuladorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimuladorUIFigure)
        end
    end
end